classdef SingleCylinderEngine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TestEditField           matlab.ui.control.EditField
        TestEditFieldLabel      matlab.ui.control.Label
        Button                  matlab.ui.control.Button
        FilenameEditField       matlab.ui.control.EditField
        FilenameEditFieldLabel  matlab.ui.control.Label
        TwEditField             matlab.ui.control.NumericEditField
        TwEditFieldLabel        matlab.ui.control.Label
        TiEditField             matlab.ui.control.NumericEditField
        TiEditFieldLabel        matlab.ui.control.Label
        RPMEditField            matlab.ui.control.NumericEditField
        RPMEditFieldLabel       matlab.ui.control.Label
        SetNrEditField          matlab.ui.control.NumericEditField
        SetNrEditFieldLabel     matlab.ui.control.Label
        Image                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.FilenameEditField.Value = ['_Set',num2str(app.SetNrEditField.Value,'%02d'),'_',num2str(app.RPMEditField.Value,'%02d'),'_',num2str(app.TiEditField.Value,'%02d'),'_',num2str(app.TwEditField.Value,'%02d')];
        end

        % Value changed function: TestEditField
        function TestEditFieldValueChanged(app, event)
            %value = app.TestEditField.Value;
            value = ['_Set',num2str(app.SetNrEditField.Value,'%02d'),'_',num2str(app.RPMEditField.Value,'%02d'),'_',num2str(app.TiEditField.Value,'%02d'),'_',num2str(app.TwEditField.Value,'%02d')];
            app.TestEditField.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ERLlogo-Gsm.png');
            app.SetNrEditFieldLabel = uilabel(app.UIFigure);
            app.SetNrEditFieldLabel.Text = 'Set Nr';
            app.SetNrEditField = uieditfield(app.UIFigure, 'numeric');
            app.RPMEditFieldLabel = uilabel(app.UIFigure);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.RPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.TiEditFieldLabel = uilabel(app.UIFigure);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.UIFigure, 'numeric');
            app.TwEditFieldLabel = uilabel(app.UIFigure);
            app.TwEditFieldLabel.Text = 'Tw';
            app.TwEditField = uieditfield(app.UIFigure, 'numeric');
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TestEditFieldLabel = uilabel(app.UIFigure);
            app.TestEditFieldLabel.Text = 'Test';
            app.TestEditField = uieditfield(app.UIFigure, 'text');
            app.TestEditField.ValueChangedFcn = createCallbackFcn(app, @TestEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SingleCylinderEngine

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end